classdef Select_dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelecttheDatasetLabel  matlab.ui.control.Label
        RUN1                   matlab.ui.control.Button
        FingerVeinCheckBox     matlab.ui.control.CheckBox
        FisherIrisCheckBox     matlab.ui.control.CheckBox
        AgarwoodoilCheckBox    matlab.ui.control.CheckBox
        GlassCheckBox          matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: RUN1
        function RUN1ButtonPushed(app, event)
            fid=fopen('C:\Users\Yugandhar\Desktop\APP\algorithm.txt','r');
            str=fscanf(fid,'%s');
            fclose(fid);
               
            
                         fid1=fopen('C:\Users\Yugandhar\Desktop\APP\database1.txt','w');
                         fid2=fopen('C:\Users\Yugandhar\Desktop\APP\database2.txt','w');
                         fid3=fopen('C:\Users\Yugandhar\Desktop\APP\database3.txt','w');
                         fid4=fopen('C:\Users\Yugandhar\Desktop\APP\database4.txt','w');
                         
                        if app.FingerVeinCheckBox.Value == true                           
                             fprintf(fid1, "1");                                    
                        else
                            fprintf(fid1, "0");
                        end                            
                        
                         if   app.FisherIrisCheckBox.Value == true
                              fprintf(fid2, "1");
                         else
                            fprintf(fid2, "0");
                         end                       
                         
                         if   app.AgarwoodoilCheckBox.Value == true
                              fprintf(fid3, "1");
                         else
                            fprintf(fid3, "0");
                         end
                                                 
                        if  app.GlassCheckBox.Value == true
                               fprintf(fid4, "1");
                         else
                            fprintf(fid4, "0");
                        end                        
                        
                         fclose(fid1);
                         fclose(fid2); 
                         fclose(fid3); 
                         fclose(fid4); 
                         
            
            if str == '1'               
                cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_nn'
                nn_switch
                
            elseif   str == '2'            
                   cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_svm'
                   switch_svm
                        
            elseif   str == '3'                  
                    cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_knn'
                    switch_knn
                                   
            elseif   str == '4'
                 cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_random'
                 switch_random
                 
            elseif   str == '5' 
                cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_logistic'
                switch_logistic
                 
                 
            elseif   str == '6'
                 cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_kmeans'
                 switch_kmeans
                 
            elseif   str == '7'
                 cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_dbscan'
                 switch_dbscan                 
                 
            else  
                cd 'C:\Users\Yugandhar\Desktop\APP\switch\switch_linear'
                 switch_linear
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelecttheDatasetLabel = uilabel(app.UIFigure);
            app.SelecttheDatasetLabel.Text = 'Select the Dataset';
            app.RUN1 = uibutton(app.UIFigure, 'push');
            app.RUN1.ButtonPushedFcn = createCallbackFcn(app, @RUN1ButtonPushed, true);
            app.RUN1.Text = 'RUN';
            app.FingerVeinCheckBox = uicheckbox(app.UIFigure);
            app.FingerVeinCheckBox.Text = 'Finger Vein';
            app.FisherIrisCheckBox = uicheckbox(app.UIFigure);
            app.FisherIrisCheckBox.Text = 'Fisher Iris';
            app.AgarwoodoilCheckBox = uicheckbox(app.UIFigure);
            app.AgarwoodoilCheckBox.Text = 'Agarwood oil';
            app.GlassCheckBox = uicheckbox(app.UIFigure);
            app.GlassCheckBox.Text = 'Glass';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Select_dataset

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end