classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Algorithm_selectUIFigure       matlab.ui.Figure
        WelcometoourprojectLabel       matlab.ui.control.Label
        AnalysisofMachineLearningAlgorithmsonRealTimeDatasetsLabel  matlab.ui.control.Label
        onLabel                        matlab.ui.control.Label
        CLassificationAlgorithmsPanel  matlab.ui.container.Panel
        List1ListBoxLabel              matlab.ui.control.Label
        List1                          matlab.ui.control.ListBox
        RUN1                           matlab.ui.control.Button
        ClusteringAlgorithmsPanel      matlab.ui.container.Panel
        List2ListBoxLabel              matlab.ui.control.Label
        List2                          matlab.ui.control.ListBox
        RUN2                           matlab.ui.control.Button
        RegressionAlgorithmPanel       matlab.ui.container.Panel
        LinearRegressionLabel          matlab.ui.control.Label
        RUN3                           matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Close request function: Algorithm_selectUIFigure
        function Algorithm_selectUIFigureCloseRequest(app, event)
            delete(app);
            
        end

        % Button pushed function: RUN1
        function RUN1ButtonPushed(app, event)
            fid=fopen('C:\Users\Yugandhar\Desktop\APP\algorithm.txt','w');
            if app.List1.Value== "Nearest Neighbour"
                fprintf(fid, "1");
            elseif app.List1.Value== "SVM"
                fprintf(fid, "2");
            elseif app.List1.Value== "K Nearest Neighbour"
                fprintf(fid, "3");
            elseif app.List1.Value== "Random Forest"
                fprintf(fid, "4");
            else
                fprintf(fid, "5");
            end
            fclose(fid);
            Select_dataset;
            delete(app);
        end

        % Button pushed function: RUN2
        function RUN2ButtonPushed(app, event)
            fid=fopen('C:\Users\Yugandhar\Desktop\APP\algorithm.txt','w');
            if app.List2.Value== 'K-Means'
                fprintf(fid, "6");
            else
                fprintf(fid, "7");
            end
            fclose(fid);
            Select_dataset;
            delete(app);
        end

        % Button pushed function: RUN3
        function RUN3ButtonPushed(app, event)
            fid=fopen('C:\Users\Yugandhar\Desktop\APP\algorithm.txt','w');
            fprintf(fid, "8");
            fclose(fid);
            Select_dataset;
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Algorithm_selectUIFigure = uifigure;
            app.Algorithm_selectUIFigure.Name = 'Algorithm_select';
            app.Algorithm_selectUIFigure.CloseRequestFcn = createCallbackFcn(app, @Algorithm_selectUIFigureCloseRequest, true);
            app.WelcometoourprojectLabel = uilabel(app.Algorithm_selectUIFigure);
            app.WelcometoourprojectLabel.Text = 'Welcome to our project ';
            app.AnalysisofMachineLearningAlgorithmsonRealTimeDatasetsLabel = uilabel(app.Algorithm_selectUIFigure);
            app.AnalysisofMachineLearningAlgorithmsonRealTimeDatasetsLabel.Text = 'Analysis of Machine Learning Algorithms on Real Time Datasets';
            app.onLabel = uilabel(app.Algorithm_selectUIFigure);
            app.onLabel.Text = 'on';
            app.CLassificationAlgorithmsPanel = uipanel(app.Algorithm_selectUIFigure);
            app.CLassificationAlgorithmsPanel.Title = 'CLassification Algorithms';
            app.List1ListBoxLabel = uilabel(app.CLassificationAlgorithmsPanel);
            app.List1ListBoxLabel.Text = 'List 1';
            app.List1 = uilistbox(app.CLassificationAlgorithmsPanel);
            app.List1.Items = {'Nearest Neighbour', 'SVM', 'K Nearest Neighbour', 'Random Forest', 'Logistic Regression'};
            app.List1.Value = 'Nearest Neighbour';
            app.RUN1 = uibutton(app.CLassificationAlgorithmsPanel, 'push');
            app.RUN1.ButtonPushedFcn = createCallbackFcn(app, @RUN1ButtonPushed, true);
            app.RUN1.Text = 'RUN';
            app.ClusteringAlgorithmsPanel = uipanel(app.Algorithm_selectUIFigure);
            app.ClusteringAlgorithmsPanel.Title = 'Clustering Algorithms';
            app.List2ListBoxLabel = uilabel(app.ClusteringAlgorithmsPanel);
            app.List2ListBoxLabel.Text = 'List 2';
            app.List2 = uilistbox(app.ClusteringAlgorithmsPanel);
            app.List2.Items = {'K-Means', 'DB Scan'};
            app.List2.Value = 'K-Means';
            app.RUN2 = uibutton(app.ClusteringAlgorithmsPanel, 'push');
            app.RUN2.ButtonPushedFcn = createCallbackFcn(app, @RUN2ButtonPushed, true);
            app.RUN2.Text = 'RUN';
            app.RegressionAlgorithmPanel = uipanel(app.Algorithm_selectUIFigure);
            app.RegressionAlgorithmPanel.Title = 'Regression Algorithm';
            app.LinearRegressionLabel = uilabel(app.RegressionAlgorithmPanel);
            app.LinearRegressionLabel.Text = 'Linear Regression';
            app.RUN3 = uibutton(app.RegressionAlgorithmPanel, 'push');
            app.RUN3.ButtonPushedFcn = createCallbackFcn(app, @RUN3ButtonPushed, true);
            app.RUN3.Text = 'RUN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Algorithm_selectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Algorithm_selectUIFigure)
        end
    end
end